classdef AeroMaps_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        BASELINEPARAMETERSPanel  matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        FRHSliderLabel           matlab.ui.control.Label
        FRHSlider                matlab.ui.control.Slider
        RRHSliderLabel           matlab.ui.control.Label
        RRHSlider                matlab.ui.control.Slider
        mmLabel                  matlab.ui.control.Label
        mmLabel_2                matlab.ui.control.Label
        SLIPSliderLabel          matlab.ui.control.Label
        SLIPSlider               matlab.ui.control.Slider
        degLabel                 matlab.ui.control.Label
        STEERSliderLabel         matlab.ui.control.Label
        STEERSlider              matlab.ui.control.Slider
        ROLLSliderLabel          matlab.ui.control.Label
        ROLLSlider               matlab.ui.control.Slider
        degLabel_2               matlab.ui.control.Label
        degLabel_3               matlab.ui.control.Label
        SWEEPBOUNDSPanel         matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        SV1DropDownLabel         matlab.ui.control.Label
        SV1DropDown              matlab.ui.control.DropDown
        UnitLabel                matlab.ui.control.Label
        MinLabel                 matlab.ui.control.Label
        MaxLabel                 matlab.ui.control.Label
        Spinner_2                matlab.ui.control.Spinner
        Spinner_3                matlab.ui.control.Spinner
        SV2DropDownLabel         matlab.ui.control.Label
        SV2DropDown              matlab.ui.control.DropDown
        Spinner                  matlab.ui.control.Spinner
        Spinner_4                matlab.ui.control.Spinner
        SWEEPVARIABLESPanel      matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        SweepVar1DropDownLabel   matlab.ui.control.Label
        SweepVar1DropDown        matlab.ui.control.DropDown
        SweepVar2DropDownLabel   matlab.ui.control.Label
        SweepVar2DropDown        matlab.ui.control.DropDown
        PLOTINGOPTIONSPanel      matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        ButtonGroup              matlab.ui.container.ButtonGroup
        ContourPlotButton        matlab.ui.control.RadioButton
        XYPlotButton             matlab.ui.control.RadioButton
        ResolutionSpinnerLabel   matlab.ui.control.Label
        ResolutionSpinner        matlab.ui.control.Spinner
        EXPORTPLOTButton         matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        AerodynamicPerformanceAnalysisToolLabel  matlab.ui.control.Label
        UPDATEPLOTSButton        matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        CLATab                   matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        CDATab                   matlab.ui.container.Tab
        UIAxes_2                 matlab.ui.control.UIAxes
        COPxTab                  matlab.ui.container.Tab
        UIAxes_3                 matlab.ui.control.UIAxes
        DownforceFWTab           matlab.ui.container.Tab
        UIAxes_4                 matlab.ui.control.UIAxes
        DownforceUTTab           matlab.ui.container.Tab
        UIAxes_5                 matlab.ui.control.UIAxes
        DownforceRWTab           matlab.ui.container.Tab
        UIAxes_6                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Kriggin Submodels
        Cl
        Cd
        Abal
        DownforceFW
        DownforceUT
        DownforceRW
    end
    methods (Static)
        function this = fcn_var_bounds(app)
            var_1_bounds = strcmp(app.SV1DropDown.Value, '[mm]') .* [app.Spinner.Value app.Spinner_2.Value]/1000 ...
                + strcmp(app.SV1DropDown.Value, '[deg]') .* deg2rad([app.Spinner.Value app.Spinner_2.Value]);
            var_2_bounds = strcmp(app.SV2DropDown.Value, '[mm]') .* [app.Spinner_3.Value app.Spinner_4.Value]/1000 ...
                + strcmp(app.SV2DropDown.Value, '[deg]') .* deg2rad([app.Spinner_3.Value app.Spinner_4.Value]);
            
            this = {var_1_bounds , var_2_bounds};
        end
        
        function fcn_mapping_Update(app)
            %% INITIALIZE
            dace_path = '/Users/antoinedemeire/Desktop/DESIGN BINDER/2018/MFE19/99 ANALYSIS_SOFTWARE/DACE';
            source_data_path = '/Users/antoinedemeire/Desktop/01 MCGILL/FSAE/E19/99_PERFORMANCE/11 Performance/MAPPING_STEP-IN/DATA';
            %%
            rho_ref = 1.1845;
            v_ref = 16;
            %%
            %% VARIABLES
            var_sweep  = {app.SweepVar1DropDown.Value , app.SweepVar2DropDown.Value};
            var_bounds = app.fcn_var_bounds(app);
            %%
            var_BSL = [app.FRHSlider.Value/1000 app.RRHSlider.Value/1000 deg2rad(app.STEERSlider.Value) deg2rad(app.SLIPSlider.Value) ...
                deg2rad(-app.ROLLSlider.Value) ];
            %% OPTIONS
            n_points = app.ResolutionSpinner.Value;
            %% ***************************************************************************************************************************
            %% ************************************************* AERO MODEL PLOTTING *****************************************************
            %% ***************************************************************************************************************************
            %%
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                            CL
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'Cl';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.Cl;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes,var_sweep{1});
                ylabel(app.UIAxes,var_sweep{2});
                %%
                title(app.UIAxes, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes, x_var , Model_out)
                xlabel(app.UIAxes, var_sweep(1))
                ylabel(app.UIAxes, var_out)
                %%
                title(app.UIAxes, [var_out ' vs. ' var_sweep{1}]);
            end
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                            CD
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'Cd';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.Cd;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes_2, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes_2,var_sweep{1});
                ylabel(app.UIAxes_2,var_sweep{2});
                %%
                title(app.UIAxes_2, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes_2, x_var , Model_out)
                xlabel(app.UIAxes_2, var_sweep(1))
                ylabel(app.UIAxes_2, var_out)
                %%
                title(app.UIAxes_2, [var_out ' vs. ' var_sweep{1}]);
            end
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                           COPx
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'Abal';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.Abal;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes_3, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes_3,var_sweep{1});
                ylabel(app.UIAxes_3,var_sweep{2});
                %%
                title(app.UIAxes_3, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes_3, x_var , Model_out)
                xlabel(app.UIAxes_3, var_sweep(1))
                ylabel(app.UIAxes_3, var_out)
                %%
                title(app.UIAxes_3, [var_out ' vs. ' var_sweep{1}]);
            end
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                         DownforceFW
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'DownforceFW';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.DownforceFW;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel)/(0.5*rho_ref*v_ref^2);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes_4, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes_4,var_sweep{1});
                ylabel(app.UIAxes_4,var_sweep{2});
                %%
                title(app.UIAxes_4, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes_4, x_var , Model_out)
                xlabel(app.UIAxes_4, var_sweep(1))
                ylabel(app.UIAxes_4, var_out)
                %%
                title(app.UIAxes_4, [var_out ' vs. ' var_sweep{1}]);
            end
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                         DownforceUT
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'DownforceUT';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.DownforceUT;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel)/(0.5*rho_ref*v_ref^2);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes_5, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes_5,var_sweep{1});
                ylabel(app.UIAxes_5,var_sweep{2});
                %%
                title(app.UIAxes_5, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes_5, x_var , Model_out)
                xlabel(app.UIAxes_5, var_sweep(1))
                ylabel(app.UIAxes_5, var_out)
                %%
                title(app.UIAxes_5, [var_out ' vs. ' var_sweep{1}]);
            end
            %% ---------------------------------------------------------------------------------------------------------------------------
            %%                                                         DownforceRW
            %% ---------------------------------------------------------------------------------------------------------------------------
            var_out    = 'DownforceRW';
            var_in  = {'FRH' , 'RRH' , 'Steer' , 'Slip' , 'Roll'};
            x_var_id = find(strcmp(var_sweep{1} , var_in) == 1);
            y_var_id = find(strcmp(var_sweep{2} , var_in) == 1);
            %%
            x_var = linspace(var_bounds{1}(1) , var_bounds{1}(2) , app.ResolutionSpinner.Value);
            y_var = linspace(var_bounds{2}(1) , var_bounds{2}(2) , app.ResolutionSpinner.Value);
            %%
            dmodel = app.DownforceRW;
            %%
            %% CONTOUR PLOT
            app.ContourPlotButton.Value
            if app.ContourPlotButton.Value
                [XX , YY] = meshgrid(x_var , y_var);
                %%
                Model_in  = [ones(length(XX(:)),1)*var_BSL(1) ones(length(XX(:)),1)*var_BSL(2) ones(length(XX(:)),1)*var_BSL(3) ...
                    ones(length(XX(:)),1)*var_BSL(4) ones(length(XX(:)),1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = XX(:);
                Model_in(: , y_var_id) = YY(:);
                %%
                Model_out = predictor(Model_in , dmodel)/(0.5*rho_ref*v_ref^2);
                %%
                Model_out = reshape(Model_out , size(XX,1) , size(XX,2));
                %%
                [C,h] = contourf(app.UIAxes_6, XX , YY , Model_out);
                clabel(C , h);
                %%
                xlabel(app.UIAxes_6,var_sweep{1});
                ylabel(app.UIAxes_6,var_sweep{2});
                %%
                title(app.UIAxes_6, [var_out ' vs. ' var_sweep{1} ' and ' var_sweep{2}]);
            else
                Model_in  = [ones(app.ResolutionSpinner.Value,1)*var_BSL(1) ones(app.ResolutionSpinner.Value,1)*var_BSL(2) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(3) ones(app.ResolutionSpinner.Value,1)*var_BSL(4) ...
                    ones(app.ResolutionSpinner.Value,1)*var_BSL(5)];
                %%
                Model_in(: , x_var_id) = x_var;
                %%
                Model_out = predictor(Model_in , dmodel);
                %%
                plot(app.UIAxes_6, x_var , Model_out)
                xlabel(app.UIAxes_6, var_sweep(1))
                ylabel(app.UIAxes_6, var_out)
                %%
                title(app.UIAxes_6, [var_out ' vs. ' var_sweep{1}]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%  VARIABLES
            dace_path = './99 ANALYSIS_SOFTWARE/DACE';
            addpath(genpath(dace_path));
            
            source_data_path = './01 Performance/MAPPING_STEP-IN/DATA';
            DATA_RAW_in = 'MFE19_MAPPING_STEP_IN_v2.csv' ;
            
            rho_ref = 1.1845;
            v_ref = 16;
            
            %%  EXTRACT DATA
            Data_Table = readtable(fullfile(source_data_path , DATA_RAW_in));
            Data_Table(:,1) = [];
            
            %%  DACEFIT
            Data_IN = {'FrontRideHeightChange' , 'RearRideHeightChange' , ...
                'Wheel_Angle' , 'SideSlip' , 'Roll'};
            
            regr = @regpoly2;
            corr = @corrgauss;
            theta_0 = 10;
            lob = [1e-1]; upb = [20];
            
            [app.Cl , ~]  = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'CLA_Mean'} , regr , corr , theta_0 , lob , upb);
            [app.Cd , ~]  = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'CDA_Mean'} , regr , corr , theta_0 , lob , upb);
            [app.Abal , ~] = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'A___Front_Aero_Balance'} , regr , corr , theta_0 ,lob , upb);
            [app.DownforceFW , ~] = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'DownforceFW'} , regr , corr , theta_0 ,lob , upb);
            [app.DownforceUT , ~] = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'DownforceUT'} , regr , corr , theta_0 ,lob , upb);
            [app.DownforceRW , ~] = dacefit(Data_Table{:,Data_IN} , Data_Table ...
                {:,'DownforceRW'} , regr , corr , theta_0 ,lob , upb);
        end

        % Button pushed function: UPDATEPLOTSButton
        function UPDATEPLOTSButtonPushed(app, event)
            app.fcn_mapping_Update(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {848, 848};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BASELINEPARAMETERSPanel = uipanel(app.LeftPanel);
            app.BASELINEPARAMETERSPanel.Title = '03 - BASELINE PARAMETERS';
            app.GridLayout7 = uigridlayout(app.BASELINEPARAMETERSPanel);
            app.FRHSliderLabel = uilabel(app.GridLayout7);
            app.FRHSliderLabel.Text = 'FRH';
            app.FRHSlider = uislider(app.GridLayout7);
            app.FRHSlider.Limits = [-30 30];
            app.RRHSliderLabel = uilabel(app.GridLayout7);
            app.RRHSliderLabel.Text = 'RRH';
            app.RRHSlider = uislider(app.GridLayout7);
            app.RRHSlider.Limits = [-30 30];
            app.mmLabel = uilabel(app.GridLayout7);
            app.mmLabel.Text = '[mm]';
            app.mmLabel_2 = uilabel(app.GridLayout7);
            app.mmLabel_2.Text = '[mm]';
            app.SLIPSliderLabel = uilabel(app.GridLayout7);
            app.SLIPSliderLabel.Text = 'SLIP';
            app.SLIPSlider = uislider(app.GridLayout7);
            app.SLIPSlider.Limits = [0 6];
            app.degLabel = uilabel(app.GridLayout7);
            app.degLabel.Text = '[deg]';
            app.STEERSliderLabel = uilabel(app.GridLayout7);
            app.STEERSliderLabel.Text = 'STEER';
            app.STEERSlider = uislider(app.GridLayout7);
            app.STEERSlider.Limits = [0 30];
            app.STEERSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.ROLLSliderLabel = uilabel(app.GridLayout7);
            app.ROLLSliderLabel.Text = 'ROLL';
            app.ROLLSlider = uislider(app.GridLayout7);
            app.ROLLSlider.Limits = [0 2.5];
            app.degLabel_2 = uilabel(app.GridLayout7);
            app.degLabel_2.Text = '[deg]';
            app.degLabel_3 = uilabel(app.GridLayout7);
            app.degLabel_3.Text = '[deg]';
            app.SWEEPBOUNDSPanel = uipanel(app.LeftPanel);
            app.SWEEPBOUNDSPanel.Title = '02 - SWEEP BOUNDS';
            app.GridLayout3 = uigridlayout(app.SWEEPBOUNDSPanel);
            app.SV1DropDownLabel = uilabel(app.GridLayout3);
            app.SV1DropDownLabel.Text = 'S.V. 1';
            app.SV1DropDown = uidropdown(app.GridLayout3);
            app.SV1DropDown.Items = {'[mm]', '[deg]'};
            app.SV1DropDown.Value = '[mm]';
            app.UnitLabel = uilabel(app.GridLayout3);
            app.UnitLabel.Text = 'Unit';
            app.MinLabel = uilabel(app.GridLayout3);
            app.MinLabel.Text = 'Min.';
            app.MaxLabel = uilabel(app.GridLayout3);
            app.MaxLabel.Text = 'Max.';
            app.Spinner_2 = uispinner(app.GridLayout3);
            app.Spinner_3 = uispinner(app.GridLayout3);
            app.Spinner_3.Value = -20;
            app.SV2DropDownLabel = uilabel(app.GridLayout3);
            app.SV2DropDownLabel.Text = 'S.V. 2';
            app.SV2DropDown = uidropdown(app.GridLayout3);
            app.SV2DropDown.Items = {'[mm]', '[deg]'};
            app.SV2DropDown.Value = '[mm]';
            app.Spinner = uispinner(app.GridLayout3);
            app.Spinner.Value = -30;
            app.Spinner_4 = uispinner(app.GridLayout3);
            app.Spinner_4.Value = 10;
            app.SWEEPVARIABLESPanel = uipanel(app.LeftPanel);
            app.SWEEPVARIABLESPanel.Title = '01 - SWEEP VARIABLES';
            app.GridLayout2 = uigridlayout(app.SWEEPVARIABLESPanel);
            app.SweepVar1DropDownLabel = uilabel(app.GridLayout2);
            app.SweepVar1DropDownLabel.Text = 'Sweep Var. 1';
            app.SweepVar1DropDown = uidropdown(app.GridLayout2);
            app.SweepVar1DropDown.Items = {'FRH', 'RRH', 'Slip', 'Steer', 'Roll'};
            app.SweepVar1DropDown.Value = 'FRH';
            app.SweepVar2DropDownLabel = uilabel(app.GridLayout2);
            app.SweepVar2DropDownLabel.Text = 'Sweep Var. 2';
            app.SweepVar2DropDown = uidropdown(app.GridLayout2);
            app.SweepVar2DropDown.Items = {'FRH', 'RRH', 'Slip', 'Steer', 'Roll'};
            app.SweepVar2DropDown.Value = 'RRH';
            app.PLOTINGOPTIONSPanel = uipanel(app.LeftPanel);
            app.PLOTINGOPTIONSPanel.Title = '04 - PLOTING OPTIONS';
            app.GridLayout8 = uigridlayout(app.PLOTINGOPTIONSPanel);
            app.ButtonGroup = uibuttongroup(app.GridLayout8);
            app.ContourPlotButton = uiradiobutton(app.ButtonGroup);
            app.ContourPlotButton.Text = 'Contour Plot';
            app.ContourPlotButton.Value = true;
            app.XYPlotButton = uiradiobutton(app.ButtonGroup);
            app.XYPlotButton.Text = 'X-Y Plot';
            app.ResolutionSpinnerLabel = uilabel(app.GridLayout8);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.GridLayout8);
            app.ResolutionSpinner.Value = 99;
            app.EXPORTPLOTButton = uibutton(app.GridLayout8, 'push');
            app.EXPORTPLOTButton.Text = 'EXPORT PLOT';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'MFE_Logo_BlackLettering.png';
            app.AerodynamicPerformanceAnalysisToolLabel = uilabel(app.LeftPanel);
            app.AerodynamicPerformanceAnalysisToolLabel.Text = {'Aerodynamic'; 'Performance '; 'Analysis Tool'};
            app.UPDATEPLOTSButton = uibutton(app.LeftPanel, 'push');
            app.UPDATEPLOTSButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEPLOTSButtonPushed, true);
            app.UPDATEPLOTSButton.Text = 'UPDATE PLOTS';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'© Antoine Demeire, Lucien Viala - McGill Formula Electric, 2018'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.CLATab = uitab(app.TabGroup);
            app.CLATab.Title = 'CLA';
            app.UIAxes = uiaxes(app.CLATab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CDATab = uitab(app.TabGroup);
            app.CDATab.Title = 'CDA';
            app.UIAxes_2 = uiaxes(app.CDATab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.COPxTab = uitab(app.TabGroup);
            app.COPxTab.Title = 'COPx';
            app.UIAxes_3 = uiaxes(app.COPxTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.DownforceFWTab = uitab(app.TabGroup);
            app.DownforceFWTab.Title = 'Downforce FW';
            app.UIAxes_4 = uiaxes(app.DownforceFWTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.DownforceUTTab = uitab(app.TabGroup);
            app.DownforceUTTab.Title = 'Downforce UT';
            app.UIAxes_5 = uiaxes(app.DownforceUTTab);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.DownforceRWTab = uitab(app.TabGroup);
            app.DownforceRWTab.Title = 'Downforce RW';
            app.UIAxes_6 = uiaxes(app.DownforceRWTab);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AeroMaps_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end